classdef BA_Versuchstand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        printPathToFileButton          matlab.ui.control.Button
        ShowTrackingCheckBox           matlab.ui.control.CheckBox
        TrackingButton                 matlab.ui.control.Button
        MeasureTextArea                matlab.ui.control.TextArea
        MeasureButton                  matlab.ui.control.Button
        ApplyROTCROPButton             matlab.ui.control.Button
        SelectBACKWARDSequenceButton   matlab.ui.control.Button
        SelectOriginandAxisButton      matlab.ui.control.Button
        PlayForwardSequenceButton      matlab.ui.control.Button
        CheckerboardCalibrationButton  matlab.ui.control.Button
        SelectFORWARDSequenceButton    matlab.ui.control.Button
        VIEW1                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cameraParams % Description
        fw_img_sequence % Description
        bw_img_sequence % Description
        cs_origin % CS Origin
        cs_rotation % Description
        cs_crop_rect % Description
        pixelsPerMM % Description
        pathCoordinates % Description
    end

    
    methods (Access = private)
        
        function resultParams = cameraParamsFromCheckerboard(app, calibrationFiles)
            % Auto-generated by cameraCalibrator app on 08-Jul-2024
            %-------------------------------------------------------

            % Define images to process
            %imageFileNames = {'C:\Users\tadde\Documents\Bachelorarbeit\Versuchstand_App\Media\M_B_sample1\M_B_sample1_calib_pattern.jpg',...
            %    'C:\Users\tadde\Documents\Bachelorarbeit\Versuchstand_App\Media\M_B_sample1\calib_calib_pattern.jpg',...
            %    };
            
            if length(calibrationFiles) >= 2;
                imageFileNames = calibrationFiles;
                % Detect calibration pattern in images
                detector = vision.calibration.monocular.CheckerboardDetector();
                [imagePoints, imagesUsed] = detectPatternPoints(detector, imageFileNames);
                imageFileNames = imageFileNames(imagesUsed);
    
                % Read the first image to obtain image size
                originalImage = imread(imageFileNames{1});
                [mrows, ncols, ~] = size(originalImage);
    
                % Generate world coordinates for the planar pattern keypoints
                squareSize = 10;  % in units of 'millimeters'
                worldPoints = generateWorldPoints(detector, 'SquareSize', squareSize);
                
                % Calibrate the camera
                [Params, imagesUsed, estimationErrors] = estimateCameraParameters(imagePoints, worldPoints, ...
                    'EstimateSkew', false, 'EstimateTangentialDistortion', false, ...
                    'NumRadialDistortionCoefficients', 2, 'WorldUnits', 'millimeters', ...
                    'InitialIntrinsicMatrix', [], 'InitialRadialDistortion', [], ...
                    'ImageSize', [mrows, ncols]);
    
                % View reprojection errors
                h1=figure; showReprojectionErrors(Params);
    
                % Visualize pattern locations
                h2=figure; showExtrinsics(Params, 'CameraCentric');
    
                % Display parameter estimation errors
                displayErrors(estimationErrors, Params);
                
                % return the camera parameters
                resultParams = Params;
            else
                error('Select at least TWO calibration images.')
            end
        end
        
        function pts = readPoint(app)
            %readPoints   Read manually-defined points from image
            %   POINTS = READPOINTS(IMAGE, N) reads up to N points only.
            [x,y] = ginput(1);
            pts = [x y];
        end
        
        
        function coord = plotPosToCS(app, point)
            disp(app.cs_origin);
            coord(1) = double(point(1))/double(app.pixelsPerMM);
            coord(2) = (double(app.cs_crop_rect(4))-double(point(2)))/double(app.pixelsPerMM);
        end
        
        function [BW,maskedRGBImage] = createMask(app, RGB)
            %createMask  Threshold RGB image using auto-generated code from colorThresholder app.
            %  [BW,MASKEDRGBIMAGE] = createMask(RGB) thresholds image RGB using
            %  auto-generated code from the colorThresholder app. The colorspace and
            %  range for each channel of the colorspace were set within the app. The
            %  segmentation mask is returned in BW, and a composite of the mask and
            %  original RGB images is returned in maskedRGBImage.

            % Auto-generated by colorThresholder app on 09-Jul-2024
            %------------------------------------------------------

            RGB = im2double(RGB);
            % Convert RGB image to chosen color space
            I = rgb2hsv(RGB);

            % Define thresholds for channel 1 based on histogram settings
            channel1Min = 0.942;
            channel1Max = 0.032;

            % Define thresholds for channel 2 based on histogram settings
            channel2Min = 0.422;
            channel2Max = 1.000;

            % Define thresholds for channel 3 based on histogram settings
            channel3Min = 0.000; %0.500
            channel3Max = 1.000;

            % Create mask based on chosen histogram thresholds
            sliderBW = ( (I(:,:,1) >= channel1Min) | (I(:,:,1) <= channel1Max) ) & ...
                (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...
                (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max);
            BW = sliderBW;

            % Initialize output masked image based on input image.
            maskedRGBImage = RGB;

            % Set background pixels where BW is false to zero.
            maskedRGBImage(repmat(~BW,[1 1 3])) = 0;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CheckerboardCalibrationButton
        function CheckerboardCalibrationButtonPushed(app, event)
            % Select Calibration Images (at least 2!)
            [calibFilenames, calibPathname] = uigetfile('*.jpg*',  'Select Calibration Images', 'MultiSelect','on');
            if isequal(calibFilenames, 0)
                disp('User selected Cancel');
            else
                calibFilenames = cellstr(calibFilenames);
    
                %if(size(calibFilenames,1) == 0)
                    
                calibFilenames=strcat(calibPathname,calibFilenames);
                cameraParams = cameraParamsFromCheckerboard(app, calibFilenames);
    
                calibImage = imread(calibFilenames{1});
                
                % use calibration data to counteract lens distortion
                undistortedCalibImage = undistortImage(calibImage, cameraParams);
    
                % Display undistorted image
                imshow(undistortedCalibImage);

                app.cameraParams = cameraParams;

                app.SelectFORWARDSequenceButton.Enable = "on";
                app.SelectBACKWARDSequenceButton.Enable = "on";
            end
        end

        % Button pushed function: SelectFORWARDSequenceButton
        function SelectFORWARDSequenceButtonPushed(app, event)
            % Delete old selection
            app.fw_img_sequence = [];

            % Select new files
            [filenames, pathname] = uigetfile('*.jpg*', 'Pick forward image sequence', 'MultiSelect','on');
            filenames=strcat(pathname,filenames);
            global fw_img_sequence;
            for i=1:numel(filenames) 
                fw_img_sequence{i} = imread(filenames{i});
                app.fw_img_sequence{i} = undistortImage(fw_img_sequence{i}, app.cameraParams);
            end
            app.SelectOriginandAxisButton.Enable = "on";
            app.PlayForwardSequenceButton.Enable = "on";
        end

        % Button pushed function: SelectBACKWARDSequenceButton
        function SelectBACKWARDSequenceButtonPushed(app, event)
            % Delete old selection
            app.bw_img_sequence = [];
            
            % Select new files
            [filenames, pathname] = uigetfile('*.jpg*', 'Pick backward image sequence', 'MultiSelect','on');
            filenames=strcat(pathname,filenames);
            for i=1:numel(filenames) 
                bw_img_sequence{i} = imread(filenames{i});
                app.bw_img_sequence{i} = undistortImage(bw_img_sequence{i}, app.cameraParams);
            end
        end

        % Button pushed function: PlayForwardSequenceButton
        function PlayForwardSequenceButtonPushed(app, event)
            for i=1:(numel(app.fw_img_sequence) + numel(app.bw_img_sequence))
                if (i<=numel(app.fw_img_sequence))
                    frame = app.fw_img_sequence{i};
                else
                    frame = app.bw_img_sequence{i-numel(app.fw_img_sequence)};
                end

                if app.ShowTrackingCheckBox.Value == 1
                    x = app.pathCoordinates{i}(1);
                    y = app.pathCoordinates{i}(2);
                    crossSize = 30;
                    frame = insertShape(frame, 'line', ...
                    {[x-crossSize, y; x+crossSize, y], [x, y-crossSize; x, y+crossSize]} ,...
                    'LineWidth', 4);
                end
                imshow(frame, 'Parent', app.VIEW1);
                pause(0.1);
            end
        end

        % Button pushed function: SelectOriginandAxisButton
        function SelectOriginandAxisButtonPushed(app, event)
            img = app.fw_img_sequence{1};
            imshow(img);

            % Select Origin
            annotation('textbox',[0 0 .2 .05],'String','Select Origin','Color', 'black', 'BackgroundColor','white','FontSize',20,'LineStyle','-', 'HorizontalAlignment','center','VerticalAlignment','middle')
            origin = readPoint(app)
            annotation('textbox',[0 0 .2 .05],'String','Select Point on X-Axis','Color', 'black', 'BackgroundColor','white','FontSize',20,'LineStyle','-', 'HorizontalAlignment','center','VerticalAlignment','middle')
            PointInXDirection = readPoint(app)
            annotation('textbox',[0 0 .2 .05],'String','Select Point on Y-Axis','Color', 'black', 'BackgroundColor','white','FontSize',20,'LineStyle','-', 'HorizontalAlignment','center','VerticalAlignment','middle')
            PointInYDirection = readPoint(app)
            close
            rot_deg_1 = atand((origin(2)-PointInXDirection(2)) / (PointInXDirection(1)-origin(1)));
            rot_deg_2 = atand((origin(1)-PointInYDirection(1)) / (origin(2)-PointInYDirection(2)));
            rot_deg_avg = (rot_deg_1+rot_deg_2)/2;

            pixelsPer200X = norm(origin - PointInXDirection);
            pixelsPer200Y = norm(origin - PointInYDirection);
            

            app.cs_origin = origin;
            app.cs_rotation = rot_deg_avg;
            app.pixelsPerMM = (pixelsPer200X+pixelsPer200Y)/(2*200);
            
            [H, W, ~] = size(img);  %Height and Width of I
            
            img_rot = imrotate(img, -rot_deg_avg , "bilinear", 'crop');

            center = [W/2 H/2]';
            R = [cosd(rot_deg_avg) -sind(rot_deg_avg); sind(rot_deg_avg) cosd(rot_deg_avg)];

            origin_rot = R*(origin' - center) + center;
            %disp(origin_rot);
            app.cs_origin = origin_rot';

            disp(app.cs_origin);
            imshow(img_rot);

            % Cropping
            vsize = 250 * app.pixelsPerMM;
            hsize = 250 * app.pixelsPerMM;
            corner_topLeft = [app.cs_origin(1) app.cs_origin(2)-hsize];
            corner_bottomRight = [hsize vsize];
            
            crop_rect = [corner_topLeft(1) corner_topLeft(2) corner_bottomRight(1) corner_bottomRight(2)];
            app.cs_crop_rect = crop_rect;
            %img_rot = insertShape(img_rot,"filled-circle",[app.cs_origin(1) app.cs_origin(2) 40]);
            %imshow(img_rot);
            %disp(crop_rect);

            img_crop = imcrop(img_rot, crop_rect);

            % show rotation and cropping preview
            imshow(img_crop);
            
            % Measure point location
            point = readPoint(app);
            X_point = point(1)/app.pixelsPerMM;
            Y_point = (app.cs_crop_rect(4)-point(2))/app.pixelsPerMM;

            disp(X_point);
            disp(Y_point);

            app.ApplyROTCROPButton.Enable = "on";
        end

        % Button pushed function: ApplyROTCROPButton
        function ApplyROTCROPButtonPushed(app, event)
            for i=1:(numel(app.fw_img_sequence)+numel(app.bw_img_sequence))
                if (i<=numel(app.fw_img_sequence))
                    app.fw_img_sequence{i} = imrotate(app.fw_img_sequence{i}, -app.cs_rotation , "bilinear", 'crop');
                    app.fw_img_sequence{i} = imcrop(app.fw_img_sequence{i}, app.cs_crop_rect);
                    imshow(app.fw_img_sequence{i}, 'Parent', app.VIEW1);
                    pause(0.1);
                else
                    k = i-numel(app.fw_img_sequence);
                    app.bw_img_sequence{k} = imrotate(app.bw_img_sequence{k}, -app.cs_rotation , "bilinear", 'crop');
                    app.bw_img_sequence{k} = imcrop(app.bw_img_sequence{k}, app.cs_crop_rect);
                    imshow(app.bw_img_sequence{k}, 'Parent', app.VIEW1);
                    pause(0.1);
                end

                app.MeasureButton.Enable = "on";
                app.TrackingButton.Enable = "on";
            end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            imshow(app.fw_img_sequence{1})
            measure_point = app.plotPosToCS(readPoint(app));

            app.MeasureTextArea.Visible = "on";
            app.MeasureTextArea.Enable = "on";
            result_string = sprintf('X: %.3f\nY: %.3f', measure_point(1), measure_point(2));
            app.MeasureTextArea.Value = result_string;
        end

        % Button pushed function: TrackingButton
        function TrackingButtonPushed(app, event)
            figure
            for i = 1:(length(app.fw_img_sequence)+length(app.bw_img_sequence))
                % Load Image
                if (i<=length(app.fw_img_sequence))
                    img = app.fw_img_sequence{i};
                else
                    img = app.bw_img_sequence{i-length(app.fw_img_sequence)};
                end

                % Mask from Hue/Saturation
                mask = createMask(app, img);

                %Fill Holes
                mask = imfill(mask,"holes");
                

                % Remove micro Disturbances
                diskElem = strel('disk', 2);
                mask = imopen(mask, diskElem);

    
                % Blob analysis
                hBlobAnalysis = vision.BlobAnalysis('MinimumBlobArea', 200, ...
                    'MaximumBlobArea', 5000);
                [objArea, objCentroid, bboxOut] = step(hBlobAnalysis, mask);
                
                bbCenterX = bboxOut(1)+bboxOut(3)/2;
                bbCenterY = bboxOut(2)+bboxOut(4)/2;

                crossSize = 30.000;
                % Preview
                preview = insertShape(img, 'line', ...
                    {[bbCenterX-crossSize, bbCenterY; bbCenterX+crossSize, bbCenterY], [bbCenterX, bbCenterY-crossSize; bbCenterX, bbCenterY+crossSize]} ,...
                    'LineWidth', 8);

                imshow(preview);
                app.pathCoordinates{i} = [bbCenterX bbCenterY];

                app.ShowTrackingCheckBox.Enable = "on";
                app.printPathToFileButton.Enable = "on";
            end


            % Initialize Tracker
%             initializeObject(tracker, img_masked(:,:,1), boxLoc, 128);
% 
            % Preview tracking
            
%             for k = 1:length(app.fw_img_sequence)
%                 img = app.fw_img_sequence{k};
%                 img_masked = filterImageByHSV(app, img, [350 60], [0.4 1.000]);
%                 bbox = step(tracker, img_masked(:,:,1));
%                 preview = insertShape(img_masked, 'rectangle', bbox, 'LineWidth',10);
%                 imshow(preview);
%                 pause(0.3);
%             end


        end

        % Button pushed function: printPathToFileButton
        function printPathToFileButtonPushed(app, event)
            T = table();
            disp(app.pathCoordinates);
            for i = 1:numel(app.pathCoordinates)
                transformedPathCoordinates = app.plotPosToCS(app.pathCoordinates{i});
                disp(transformedPathCoordinates);
                T = [T; {i-1}, {transformedPathCoordinates(1)}, {transformedPathCoordinates(2)}];
            end

            [file,location,indx] = uiputfile('M_X_sample_X_measuredPath.xls');

            if isequal(file,0) || isequal(location,0)
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(location,file),...
                    ' and then clicked Save.'])
            end
            % disp(T);
            writetable(T, fullfile(location,file));
        end

        % Callback function: not associated with a component
        function debugButtonPushed(app, event)
            %app.DebugTextArea.Value = num2cell(app.cameraParams);
            disp(app.pixelsPerMM);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VIEW1 = uiaxes(app.UIFigure);
            title(app.VIEW1, 'View 1')
            app.SelectFORWARDSequenceButton = uibutton(app.UIFigure, 'push');
            app.SelectFORWARDSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFORWARDSequenceButtonPushed, true);
            app.SelectFORWARDSequenceButton.Icon = fullfile(pathToMLAPP, 'ui', 'icons_library', 'MaterialDesign', 'mat-folder-open.png');
            app.SelectFORWARDSequenceButton.Enable = 'off';
            app.SelectFORWARDSequenceButton.Text = 'Select FORWARD Sequence';
            app.CheckerboardCalibrationButton = uibutton(app.UIFigure, 'push');
            app.CheckerboardCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CheckerboardCalibrationButtonPushed, true);
            app.CheckerboardCalibrationButton.Text = 'Checkerboard Calibration';
            app.PlayForwardSequenceButton = uibutton(app.UIFigure, 'push');
            app.PlayForwardSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @PlayForwardSequenceButtonPushed, true);
            app.PlayForwardSequenceButton.Enable = 'off';
            app.PlayForwardSequenceButton.Text = 'Play Forward Sequence';
            app.SelectOriginandAxisButton = uibutton(app.UIFigure, 'push');
            app.SelectOriginandAxisButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOriginandAxisButtonPushed, true);
            app.SelectOriginandAxisButton.Enable = 'off';
            app.SelectOriginandAxisButton.Text = 'Select Origin and Axis';
            app.SelectBACKWARDSequenceButton = uibutton(app.UIFigure, 'push');
            app.SelectBACKWARDSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBACKWARDSequenceButtonPushed, true);
            app.SelectBACKWARDSequenceButton.Icon = fullfile(pathToMLAPP, 'ui', 'icons_library', 'MaterialDesign', 'mat-folder-open.png');
            app.SelectBACKWARDSequenceButton.Enable = 'off';
            app.SelectBACKWARDSequenceButton.Text = 'Select BACKWARD Sequence';
            app.ApplyROTCROPButton = uibutton(app.UIFigure, 'push');
            app.ApplyROTCROPButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyROTCROPButtonPushed, true);
            app.ApplyROTCROPButton.Enable = 'off';
            app.ApplyROTCROPButton.Text = 'Apply ROT / CROP';
            app.MeasureButton = uibutton(app.UIFigure, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Icon = fullfile(pathToMLAPP, 'ui', 'icons_library', 'MaterialDesign', 'mat-colorize.png');
            app.MeasureButton.Enable = 'off';
            app.MeasureButton.Text = '';
            app.MeasureTextArea = uitextarea(app.UIFigure);
            app.MeasureTextArea.Editable = 'off';
            app.MeasureTextArea.Enable = 'off';
            app.MeasureTextArea.Visible = 'off';
            app.TrackingButton = uibutton(app.UIFigure, 'push');
            app.TrackingButton.ButtonPushedFcn = createCallbackFcn(app, @TrackingButtonPushed, true);
            app.TrackingButton.Enable = 'off';
            app.TrackingButton.Text = 'Tracking';
            app.ShowTrackingCheckBox = uicheckbox(app.UIFigure);
            app.ShowTrackingCheckBox.Text = 'Show Tracking';
            app.printPathToFileButton = uibutton(app.UIFigure, 'push');
            app.printPathToFileButton.ButtonPushedFcn = createCallbackFcn(app, @printPathToFileButtonPushed, true);
            app.printPathToFileButton.Icon = fullfile(pathToMLAPP, 'ui', 'icons_library', 'MaterialDesign', 'mat-file-download.png');
            app.printPathToFileButton.Enable = 'off';
            app.printPathToFileButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BA_Versuchstand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end